classdef FOSWEC2app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        HWRLShareSwitch                matlab.ui.control.Switch
        HWRLShareSwitchLabel           matlab.ui.control.Label
        BowBusVEditField               matlab.ui.control.NumericEditField
        BowBusVEditFieldLabel          matlab.ui.control.Label
        AftBusVEditField               matlab.ui.control.NumericEditField
        AftBusVEditFieldLabel          matlab.ui.control.Label
        EnclosurePressurePSIEditField  matlab.ui.control.NumericEditField
        EnclosurePressurePSILabel      matlab.ui.control.Label
        TETsEditField                  matlab.ui.control.NumericEditField
        TETsEditFieldLabel             matlab.ui.control.Label
        BowDriveStatusEditField        matlab.ui.control.EditField
        BowDriveStatusEditFieldLabel   matlab.ui.control.Label
        AftDriveStatusEditField        matlab.ui.control.EditField
        AftDriveStatusEditFieldLabel   matlab.ui.control.Label
        FeedbackPanel                  matlab.ui.container.Panel
        EnableFeedbackControlButton    matlab.ui.control.Button
        flapframeStiffNmDampNmsLabel   matlab.ui.control.Label
        BowDampCCSpinner               matlab.ui.control.Spinner
        BowDampCCSpinnerLabel          matlab.ui.control.Label
        BowStiffCCSpinner              matlab.ui.control.Spinner
        BowStiffCCSpinnerLabel         matlab.ui.control.Label
        BowDampingSpinner              matlab.ui.control.Spinner
        BowDampingSpinnerLabel         matlab.ui.control.Label
        BowStiffnessSpinner            matlab.ui.control.Spinner
        BowStiffnessSpinnerLabel       matlab.ui.control.Label
        AftDampCCSpinner               matlab.ui.control.Spinner
        AftDampCCSpinnerLabel          matlab.ui.control.Label
        AftStiffCCSpinner              matlab.ui.control.Spinner
        AftStiffCCSpinnerLabel         matlab.ui.control.Label
        AftDampingSpinner              matlab.ui.control.Spinner
        AftDampingSpinnerLabel         matlab.ui.control.Label
        AftStiffnessSpinner            matlab.ui.control.Spinner
        AftStiffnessSpinnerLabel       matlab.ui.control.Label
        SourceDropDown                 matlab.ui.control.DropDown
        SourceDropDownLabel            matlab.ui.control.Label
        ReferencePanel                 matlab.ui.container.Panel
        RampDownButton                 matlab.ui.control.Button
        RampUpButton                   matlab.ui.control.Button
        SinePeriodSpinner              matlab.ui.control.Spinner
        SinePeriodSpinnerLabel         matlab.ui.control.Label
        BowAmplitudeSpinner            matlab.ui.control.Spinner
        BowAmplitudeSpinnerLabel       matlab.ui.control.Label
        AftAmplitudeSpinner            matlab.ui.control.Spinner
        AftAmplitudeSpinnerLabel       matlab.ui.control.Label
        BowReferenceDropDown           matlab.ui.control.DropDown
        BowReferenceDropDownLabel      matlab.ui.control.Label
        AftReferenceDropDown           matlab.ui.control.DropDown
        AftReferenceDropDownLabel      matlab.ui.control.Label
        eCATstateEditField             matlab.ui.control.NumericEditField
        eCATstateEditFieldLabel        matlab.ui.control.Label
        controlWordBowEditField        matlab.ui.control.NumericEditField
        controlBowEditFieldLabel       matlab.ui.control.Label
        controlWordAftEditField        matlab.ui.control.NumericEditField
        controlAftEditFieldLabel       matlab.ui.control.Label
        statusWordBowEditField         matlab.ui.control.NumericEditField
        statusBowEditFieldLabel        matlab.ui.control.Label
        statusWordAftEditField         matlab.ui.control.NumericEditField
        statusAftEditFieldLabel        matlab.ui.control.Label
        EnableDrivesButton             matlab.ui.control.Button
        ClearFaultsButton              matlab.ui.control.Button
        StartStopButton                matlab.ui.control.Button
        CurrentLimitSpinner            matlab.ui.control.Spinner
        CurrentLimitSpinnerLabel       matlab.ui.control.Label
        TrialSpinner                   matlab.ui.control.Spinner
        TrialLabel                     matlab.ui.control.Label
        ExperimentEditField            matlab.ui.control.EditField
        ExperimentEditFieldLabel       matlab.ui.control.Label
        ProjectEditField               matlab.ui.control.EditField
        ProjectEditFieldLabel          matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        Panel_2                        matlab.ui.container.Panel
        BowFlapRotationdegGauge        matlab.ui.control.SemicircularGauge
        BowFlapRotationdegGaugeLabel   matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        AftFlapRotationdegGauge        matlab.ui.control.SemicircularGauge
        AftFlapRotationdegGaugeLabel   matlab.ui.control.Label
        StatusBar                      slrealtime.ui.control.StatusBar
        UIAxes8                        matlab.ui.control.UIAxes
        UIAxes7                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        buildDir
        mdlName = evalin('base','mdlName');

        hInst
        tg
        tgName
        aftDriveStatus
        bowDriveStatus
        ds


        startIcon = fullfile('icons','slrtRunIcon.png')
        stopIcon = fullfile('icons','slrtStopIcon.png')
    end

    methods (Access = private)

        function setupInstrumentation(app)
            app.tg.removeAllInstruments;
            app.hInst = slrealtime.Instrument(app.mdlName);
            
            app.hInst.connectLine(app.UIAxes, [app.mdlName,'/driveInputs/aftCntsTOamps'],1);
            app.hInst.connectLine(app.UIAxes, [app.mdlName,'/aftTarget_A'],1);
            legend(app.UIAxes,'Aft Measured','Aft Commanded');
            grid(app.UIAxes,'on')
 
            app.hInst.connectLine(app.UIAxes2, [app.mdlName,'/driveInputs/bowCntsTOamps'],1);
            app.hInst.connectLine(app.UIAxes2, [app.mdlName,'/bowTarget_A'],1);
            legend(app.UIAxes2,'Bow Measured','Bow Commanded');
            grid(app.UIAxes2,'on')


            app.hInst.connectLine(app.UIAxes3, [app.mdlName,'/driveInputs/aftmotor2flap'],1);
            % app.hInst.connectLine(app.UIAxes3, [app.mdlName,'/driveInputs/aftMotorRadPerS'],1);
%             app.hInst.connectLine(app.UIAxes3, [app.mdlName,'/FeedbackControl/aftMradpersFilt'],1);
            % app.UIAxes3.YLim = [-50 50];
            grid(app.UIAxes3,'on')
            

            app.hInst.connectLine(app.UIAxes4, [app.mdlName,'/driveInputs/bowmotor2flap'],1);
            % app.hInst.connectLine(app.UIAxes4, [app.mdlName,'/driveInputs/bowMotorRadPerS'],1);
%             app.hInst.connectLine(app.UIAxes4, [app.mdlName,'/FeedbackControl/bowMradpersFilt'],1);
            % app.UIAxes4.YLim = [-50 50];
            grid(app.UIAxes4,'on')


            app.hInst.connectLine(app.UIAxes5, [app.mdlName,'/driveInputs/aftMotor_W'],1);
            app.hInst.connectLine(app.UIAxes5, [app.mdlName,'/Electrical Power/aftInverter_W'],1);
            app.hInst.connectLine(app.UIAxes5,[app.mdlName,'/Electrical Power/Aft_P_W'],1);
            legend(app.UIAxes5,'motor','inverter','capacitor');
            grid(app.UIAxes5,'on')


            app.hInst.connectLine(app.UIAxes6, [app.mdlName,'/driveInputs/bowMotor_W'],1);
            app.hInst.connectLine(app.UIAxes6, [app.mdlName,'/Electrical Power/bowInverter_W'],1);
            app.hInst.connectLine(app.UIAxes6,[app.mdlName,'/Electrical Power/Bow_P_W'],1);
            legend(app.UIAxes6,'motor','inverter','capacitor');
            grid(app.UIAxes6,'on')

            app.hInst.connectLine(app.UIAxes7, [app.mdlName,'/aftdamping'],1);
            grid(app.UIAxes7,'on')

            app.hInst.connectLine(app.UIAxes8, [app.mdlName,'/bowdamping'],1);
            grid(app.UIAxes8,'on')


            app.hInst.connectScalar(app.AftFlapRotationdegGauge,[app.mdlName,'/driveInputs/aftmotor2flap'],1,'Decimation',100,'Callback',@(t,d)double(d))
            app.hInst.connectScalar(app.BowFlapRotationdegGauge,[app.mdlName,'/driveInputs/bowmotor2flap'],1,'Decimation',100,'Callback',@(t,d)double(d))
            app.hInst.connectScalar(app.EnclosurePressurePSIEditField,[app.mdlName,'/Sensor Inputs/cnts2psi'],1)

            app.hInst.connectScalar(app.eCATstateEditField,[app.mdlName,'/Initialization/eCATstate'],1)
            app.hInst.connectScalar(app.statusWordAftEditField,[app.mdlName,'/Initialization/statusWordAft'],1)
            app.hInst.connectScalar(app.statusWordBowEditField,[app.mdlName,'/Initialization/statusWordBow'],1)
            app.hInst.connectScalar(app.controlWordAftEditField,[app.mdlName,'/Initialization/controlWordAft'],1)
            app.hInst.connectScalar(app.controlWordBowEditField,[app.mdlName,'/Initialization/controlWordBow'],1)
            app.hInst.connectScalar(app.AftBusVEditField,[app.mdlName,'/driveInputs/aftCntsTOVolts'],1)
            app.hInst.connectScalar(app.BowBusVEditField,[app.mdlName,'/driveInputs/bowCntsTOVolts'],1)

            app.aftDriveStatus = 'Aftinit';
            app.bowDriveStatus = 'Bowinit';
            app.AftDriveStatusEditField.Value = app.aftDriveStatus;
            app.BowDriveStatusEditField.Value = app.bowDriveStatus;

            app.hInst.connectCallback(@app.regularCallback)


%             legend(app.UIAxes3,'Actual','Filtered','AutoUpdate','off');
%             legend(app.UIAxes4,'Actual','Filtered','AutoUpdate','off');
          
             app.tg.addInstrument(app.hInst)

        end
        function regularCallback(app,~,eventData)
            % bow drive status decode
            value = app.statusWordAftEditField.Value;
            dS = bitand(value, 0b0000000000111111);
            if dS == 0 || dS == 16 || dS == 32 || dS == 48
                app.aftDriveStatus = 'Not Ready to Switch On';
            elseif dS == 64 || dS == 80 || dS == 96 || dS == 112
                app.aftDriveStatus = 'Switch On Disabled';
            elseif dS == 33 || dS == 49
                app.aftDriveStatus = 'Ready to Switch On';
            elseif dS == 35 || dS == 51
                app.aftDriveStatus = 'Switched On';
            elseif dS == 39 || dS == 55
                app.aftDriveStatus = 'Operations Enabled';
            elseif dS == 15 || dS == 31 || dS == 63 || dS == 79
                app.aftDriveStatus = 'Fault Reaction Active';
            elseif dS == 8 || dS == 24 || dS == 40 || dS == 86
                app.aftDriveStatus = 'Fault';
            elseif dS == 7 || dS == 23
                app.aftDriveStatus = 'Quick Stop Active';
            else
                app.aftDriveStatus = 'Unknown';
            end
            app.AftDriveStatusEditField.Value = app.aftDriveStatus;
            
            % bow drive status decode
            value = app.statusWordBowEditField.Value;
            dS = bitand(value, 0b0000000000111111);
            if dS == 0 || dS == 16 || dS == 32 || dS == 48
                app.bowDriveStatus = 'Not Ready to Switch On';
            elseif dS == 64 || dS == 80 || dS == 96 || dS == 112
                app.bowDriveStatus = 'Switch On Disabled';
            elseif dS == 33 || dS == 49
                app.bowDriveStatus = 'Ready to Switch On';
            elseif dS == 35 || dS == 51
                app.bowDriveStatus = 'Switched On';
            elseif dS == 39 || dS == 55
                app.bowDriveStatus = 'Operations Enabled';
            elseif dS == 15 || dS == 31 || dS == 63 || dS == 79
                app.bowDriveStatus = 'Fault Reaction Active';
            elseif dS == 8 || dS == 24 || dS == 40 || dS == 86
                app.bowDriveStatus = 'Fault';
            elseif dS == 7 || dS == 23
                app.bowDriveStatus = 'Quick Stop Active';
            else
                app.bowDriveStatus = 'Unknown';
            end
            app.BowDriveStatusEditField.Value = app.bowDriveStatus;

            % TET harvest
            s = app.tg.ModelStatus;
            if (sum(size(s.TETInfo)) >= 1)  % could be more than one entry
                tet = s.TETInfo(1);
                app.TETsEditField.Value = double(tet.TETAvg);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'FOSWEC2';
            drawnow;
            app.UIFigure.WindowState = 'maximized';

            app.tgName = evalin('base','tgName');
            app.tg = slrealtime(app.tgName);

            app.ds = evalin('base','ds');

            app.tg.stop;
            app.tg.removeAllInstruments();

            app.buildDir = evalin('base','buildDir');
            app.tg.load(fullfile(app.buildDir,app.mdlName));

            app.StartStopButton.Icon = app.startIcon;
            app.StartStopButton.Text = 'Start';

            app.ClearFaultsButton.Text = 'Clear Faults';

            app.EnableDrivesButton.Text = 'Enable Drive';
            app.EnableDrivesButton.BackgroundColor = 'Red';

            app.EnableFeedbackControlButton.Text = 'Enable Ctrl';
            app.EnableFeedbackControlButton.BackgroundColor = 'Red';

            app.setupInstrumentation;
        end

        % Button pushed function: StartStopButton
        function StartStopButtonButtonPushed(app, event)
            if strcmp(app.StartStopButton.Icon,app.startIcon)
                app.setupInstrumentation;
                app.tg.start('ReloadOnStop',true,'AutoImportFileLog',false);
                app.StartStopButton.Icon = app.stopIcon;
                app.StartStopButton.Text = 'Stop';
            else
                app.tg.stop;
                app.StartStopButton.Icon = app.startIcon;
                app.StartStopButton.Text = 'Start';
                run('postProcess.m');
            end
        end

        % Value changed function: AftReferenceDropDown
        function AftReferenceDropDownValueChanged(app, event)
            value = app.AftReferenceDropDown.Value;
            if strcmp(value,'None')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch'],'Value',1)
            elseif strcmp(value,'Ramps')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch'],'Value',2)
            elseif strcmp(value,'Sine')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch'],'Value',3)
            elseif strcmp(value,'MS1')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch'],'Value',4)
            elseif strcmp(value,'MS2')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch'],'Value',5)
            elseif strcmp(value,'MS3')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch'],'Value',6)
            elseif strcmp(value,'Chirp')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch'],'Value',7)
            elseif strcmp(value,'BandLimitedWN')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch'],'Value',8)                
            end

        end

        % Value changed function: BowReferenceDropDown
        function BowReferenceDropDownValueChanged(app, event)
            value = app.BowReferenceDropDown.Value;
            if strcmp(value,'None')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch1'],'Value',1)
            elseif strcmp(value,'Ramps')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch1'],'Value',2)
            elseif strcmp(value,'Sine')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch1'],'Value',3)
            elseif strcmp(value,'MS1')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch1'],'Value',4)
            elseif strcmp(value,'MS2')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch1'],'Value',5)
            elseif strcmp(value,'MS3')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch1'],'Value',6)
            elseif strcmp(value,'Chirp')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch1'],'Value',7)
            elseif strcmp(value,'BandLimitedWN')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch1'],'Value',8)  
            end

        end

        % Value changed function: CurrentLimitSpinner
        function CurrentLimitSpinnerValueChanged(app, event)
            value = app.CurrentLimitSpinner.Value;
            app.tg.setparam([app.mdlName,'/aftCurrentLimit'],'Value',value)
            app.tg.setparam([app.mdlName,'/bowCurrentLimit'],'Value',value)

        end

        % Button pushed function: ClearFaultsButton
        function ClearFaultsButtonPushed(app, event)
            app.tg.setparam([app.mdlName,'/Initialization/clearFaults'],'Value',1);
            app.ClearFaultsButton.Text = 'Faults Clearing';
            pause(1);
            app.tg.setparam([app.mdlName,'/Initialization/clearFaults'],'Value',0);
            app.ClearFaultsButton.Text = 'Clear Faults';
        end

        % Button pushed function: EnableDrivesButton
        function EnableDrivesButtonPushed(app, event)
            if strcmp(app.EnableDrivesButton.Text,'Enable Drive')
                app.tg.setparam([app.mdlName,'/Initialization/enableDrives'],'Value',1);
                app.EnableDrivesButton.Text = 'Drive Enabled';
                app.EnableDrivesButton.BackgroundColor = 'Green';

            else
                app.tg.setparam([app.mdlName,'/Initialization/enableDrives'],'Value',0);
                app.EnableDrivesButton.Text = 'Enable Drive';
                app.EnableDrivesButton.BackgroundColor = 'Red';
            end
        end

        % Value changed function: AftAmplitudeSpinner
        function AftAmplitudeSpinnerValueChanged(app, event)
            value = app.AftAmplitudeSpinner.Value;
            app.tg.setparam([app.mdlName,'/aftTarget_A'],'Gain',value)
        end

        % Value changed function: BowAmplitudeSpinner
        function BowAmplitudeSpinnerValueChanged(app, event)
            value = app.BowAmplitudeSpinner.Value;
            app.tg.setparam([app.mdlName,'/bowTarget_A'],'Gain',value)
        end

        % Value changed function: SourceDropDown
        function SourceDropDownValueChanged(app, event)
            value = app.SourceDropDown.Value;
            if strcmp(value,'Manual')
                app.tg.setparam([app.mdlName,'/FeedbackControl/ctrlType'],'Value',1)
            elseif strcmp(value,'Excel')
                app.tg.setparam([app.mdlName,'/FeedbackControl/ctrlType'],'Value',2)
            end
        end

        % Button pushed function: EnableFeedbackControlButton
        function EnableFeedbackControlButtonPushed(app, event)
            if strcmp(app.EnableFeedbackControlButton.Text,'Enable Ctrl')
                app.tg.setparam([app.mdlName,'/FeedbackControl/enableControl'],'Value',1);
                app.EnableFeedbackControlButton.Text = 'Ctrl Enabled';
                app.EnableFeedbackControlButton.BackgroundColor = 'Green';

            else
                app.tg.setparam([app.mdlName,'/FeedbackControl/enableControl'],'Value',0);
                app.EnableFeedbackControlButton.Text = 'Enable Ctrl';
                app.EnableFeedbackControlButton.BackgroundColor = 'Red';
            end
        end

        % Value changed function: AftStiffnessSpinner
        function AftStiffnessSpinnerValueChanged(app, event)
            value = app.AftStiffnessSpinner.Value;
            app.tg.setparam([app.mdlName,'/FeedbackControl/aftStiffness'],'Value',value)
        end

        % Value changed function: AftDampingSpinner
        function AftDampingSpinnerValueChanged(app, event)
            value = app.AftDampingSpinner.Value;
            app.tg.setparam([app.mdlName,'/FeedbackControl/aftDamping'],'Value',value)
        end

        % Value changed function: AftStiffCCSpinner
        function AftStiffCCSpinnerValueChanged(app, event)
            value = app.AftStiffCCSpinner.Value;
            app.tg.setparam([app.mdlName,'/FeedbackControl/aftStiffnessCrossCoupling'],'Value',value)
        end

        % Value changed function: AftDampCCSpinner
        function AftDampCCSpinnerValueChanged(app, event)
            value = app.AftDampCCSpinner.Value;
            app.tg.setparam([app.mdlName,'/FeedbackControl/aftDampingCrossCoupling'],'Value',value)
        end

        % Value changed function: BowStiffnessSpinner
        function BowStiffnessSpinnerValueChanged(app, event)
            value = app.BowStiffnessSpinner.Value;
            app.tg.setparam([app.mdlName,'/FeedbackControl/bowStiffness'],'Value',value)
        end

        % Value changed function: BowDampingSpinner
        function BowDampingSpinnerValueChanged(app, event)
            value = app.BowDampingSpinner.Value;
            app.tg.setparam([app.mdlName,'/FeedbackControl/bowDamping'],'Value',value)
        end

        % Value changed function: BowStiffCCSpinner
        function BowStiffCCSpinnerValueChanged(app, event)
            value = app.BowStiffCCSpinner.Value;
            app.tg.setparam([app.mdlName,'/FeedbackControl/bowStiffnessCrossCoupling'],'Value',value)
        end

        % Value changed function: BowDampCCSpinner
        function BowDampCCSpinnerValueChanged(app, event)
            value = app.BowDampCCSpinner.Value;
            app.tg.setparam([app.mdlName,'/FeedbackControl/bowDampingCrossCoupling'],'Value',value)
        end

        % Value changed function: HWRLShareSwitch
        function HWRLShareSwitchValueChanged(app, event)
            value = app.HWRLShareSwitch.Value;
            
        end

        % Value changed function: SinePeriodSpinner
        function SinePeriodSpinnerValueChanged2(app, event)
            value = app.SinePeriodSpinner.Value;
            app.tg.setparam([app.mdlName,'/Reference/Reference Signals/sineWave/Sine Wave'],'Frequency',2*pi/value)
        end

        % Button pushed function: RampUpButton
        function RampUpButtonPushed(app, event)
            if strcmp(app.RampUpButton.Text,'Ramp Up')
                app.tg.setparam([app.mdlName,'/Initialization/EnableRamps'],'Value',1)
                app.RampUpButton.Text = 'Ramped';
                app.RampUpButton.Enable = 'off';

                app.RampDownButton.Enable = 'on';
                app.RampDownButton.Text = 'Ramp Down';
            end
        end

        % Button pushed function: RampDownButton
        function RampDownButtonPushed(app, event)
            if strcmp(app.RampDownButton.Text,'Ramp Down')
                app.tg.setparam([app.mdlName,'/Initialization/EnableRamps'],'Value',0)
                app.RampDownButton.Text = 'Ramped';
                app.RampDownButton.Enable = 'off';

                app.RampUpButton.Enable = 'on';
                app.RampUpButton.Text = 'Ramp Up';
            end  
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {890, 890};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {432, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'FOSWEC2 Interface';
            app.ProjectEditFieldLabel = uilabel(app.LeftPanel);
            app.ProjectEditFieldLabel.Text = 'Project';
            app.ProjectEditField = uieditfield(app.LeftPanel, 'text');
            app.ProjectEditField.Editable = 'off';
            app.ProjectEditField.Value = 'NAVFACOSWECS';
            app.ExperimentEditFieldLabel = uilabel(app.LeftPanel);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.LeftPanel, 'text');
            app.TrialLabel = uilabel(app.LeftPanel);
            app.TrialLabel.Text = 'Trial';
            app.TrialSpinner = uispinner(app.LeftPanel);
            app.TrialSpinner.Limits = [0 Inf];
            app.TrialSpinner.RoundFractionalValues = 'on';
            app.TrialSpinner.Value = 1;
            app.CurrentLimitSpinnerLabel = uilabel(app.LeftPanel);
            app.CurrentLimitSpinnerLabel.Text = 'Current Limit';
            app.CurrentLimitSpinner = uispinner(app.LeftPanel);
            app.CurrentLimitSpinner.Limits = [0 18];
            app.CurrentLimitSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentLimitSpinnerValueChanged, true);
            app.StartStopButton = uibutton(app.LeftPanel, 'push');
            app.StartStopButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopButtonButtonPushed, true);
            app.StartStopButton.Text = 'StartStop';
            app.ClearFaultsButton = uibutton(app.LeftPanel, 'push');
            app.ClearFaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFaultsButtonPushed, true);
            app.ClearFaultsButton.Text = 'Clear Faults';
            app.EnableDrivesButton = uibutton(app.LeftPanel, 'push');
            app.EnableDrivesButton.ButtonPushedFcn = createCallbackFcn(app, @EnableDrivesButtonPushed, true);
            app.EnableDrivesButton.Text = 'Enable Drives';
            app.statusAftEditFieldLabel = uilabel(app.LeftPanel);
            app.statusAftEditFieldLabel.Text = 'statusAft';
            app.statusWordAftEditField = uieditfield(app.LeftPanel, 'numeric');
            app.statusBowEditFieldLabel = uilabel(app.LeftPanel);
            app.statusBowEditFieldLabel.Text = 'statusBow';
            app.statusWordBowEditField = uieditfield(app.LeftPanel, 'numeric');
            app.controlAftEditFieldLabel = uilabel(app.LeftPanel);
            app.controlAftEditFieldLabel.Text = 'controlAft';
            app.controlWordAftEditField = uieditfield(app.LeftPanel, 'numeric');
            app.controlBowEditFieldLabel = uilabel(app.LeftPanel);
            app.controlBowEditFieldLabel.Text = 'controlBow';
            app.controlWordBowEditField = uieditfield(app.LeftPanel, 'numeric');
            app.eCATstateEditFieldLabel = uilabel(app.LeftPanel);
            app.eCATstateEditFieldLabel.Text = 'eCATstate';
            app.eCATstateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ReferencePanel = uipanel(app.LeftPanel);
            app.ReferencePanel.Title = 'Reference';
            app.AftReferenceDropDownLabel = uilabel(app.ReferencePanel);
            app.AftReferenceDropDownLabel.Text = 'Aft Reference';
            app.AftReferenceDropDown = uidropdown(app.ReferencePanel);
            app.AftReferenceDropDown.Items = {'None', 'Ramps', 'Sine', 'MS1', 'MS2', 'MS3', 'Chirp', 'BandLimitedWN'};
            app.AftReferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @AftReferenceDropDownValueChanged, true);
            app.AftReferenceDropDown.Value = 'None';
            app.BowReferenceDropDownLabel = uilabel(app.ReferencePanel);
            app.BowReferenceDropDownLabel.Text = 'Bow Reference';
            app.BowReferenceDropDown = uidropdown(app.ReferencePanel);
            app.BowReferenceDropDown.Items = {'None', 'Ramps', 'Sine', 'MS1', 'MS2', 'MS3', 'Chirp', 'BandLimitedWN'};
            app.BowReferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @BowReferenceDropDownValueChanged, true);
            app.BowReferenceDropDown.Value = 'None';
            app.AftAmplitudeSpinnerLabel = uilabel(app.ReferencePanel);
            app.AftAmplitudeSpinnerLabel.Text = 'Aft Amplitude';
            app.AftAmplitudeSpinner = uispinner(app.ReferencePanel);
            app.AftAmplitudeSpinner.Step = 2.5;
            app.AftAmplitudeSpinner.Limits = [0 18];
            app.AftAmplitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @AftAmplitudeSpinnerValueChanged, true);
            app.BowAmplitudeSpinnerLabel = uilabel(app.ReferencePanel);
            app.BowAmplitudeSpinnerLabel.Text = 'Bow Amplitude';
            app.BowAmplitudeSpinner = uispinner(app.ReferencePanel);
            app.BowAmplitudeSpinner.Step = 2.5;
            app.BowAmplitudeSpinner.Limits = [0 18];
            app.BowAmplitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @BowAmplitudeSpinnerValueChanged, true);
            app.SinePeriodSpinnerLabel = uilabel(app.ReferencePanel);
            app.SinePeriodSpinnerLabel.Text = 'Sine Period';
            app.SinePeriodSpinner = uispinner(app.ReferencePanel);
            app.SinePeriodSpinner.Step = 0.5;
            app.SinePeriodSpinner.Limits = [1 10];
            app.SinePeriodSpinner.ValueChangedFcn = createCallbackFcn(app, @SinePeriodSpinnerValueChanged2, true);
            app.SinePeriodSpinner.Value = 2;
            app.RampUpButton = uibutton(app.ReferencePanel, 'push');
            app.RampUpButton.ButtonPushedFcn = createCallbackFcn(app, @RampUpButtonPushed, true);
            app.RampUpButton.Text = 'Ramp Up';
            app.RampDownButton = uibutton(app.ReferencePanel, 'push');
            app.RampDownButton.ButtonPushedFcn = createCallbackFcn(app, @RampDownButtonPushed, true);
            app.RampDownButton.Text = 'Ramp Down';
            app.FeedbackPanel = uipanel(app.LeftPanel);
            app.FeedbackPanel.Title = 'Feedback';
            app.SourceDropDownLabel = uilabel(app.FeedbackPanel);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.FeedbackPanel);
            app.SourceDropDown.Items = {'Manual', 'Excel'};
            app.SourceDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceDropDownValueChanged, true);
            app.SourceDropDown.Value = 'Manual';
            app.AftStiffnessSpinnerLabel = uilabel(app.FeedbackPanel);
            app.AftStiffnessSpinnerLabel.Text = 'Aft Stiffness';
            app.AftStiffnessSpinner = uispinner(app.FeedbackPanel);
            app.AftStiffnessSpinner.ValueChangedFcn = createCallbackFcn(app, @AftStiffnessSpinnerValueChanged, true);
            app.AftDampingSpinnerLabel = uilabel(app.FeedbackPanel);
            app.AftDampingSpinnerLabel.Text = 'Aft Damping';
            app.AftDampingSpinner = uispinner(app.FeedbackPanel);
            app.AftDampingSpinner.Step = 5;
            app.AftDampingSpinner.Limits = [0 200];
            app.AftDampingSpinner.ValueChangedFcn = createCallbackFcn(app, @AftDampingSpinnerValueChanged, true);
            app.AftStiffCCSpinnerLabel = uilabel(app.FeedbackPanel);
            app.AftStiffCCSpinnerLabel.Text = 'Aft Stiff CC';
            app.AftStiffCCSpinner = uispinner(app.FeedbackPanel);
            app.AftStiffCCSpinner.ValueChangedFcn = createCallbackFcn(app, @AftStiffCCSpinnerValueChanged, true);
            app.AftDampCCSpinnerLabel = uilabel(app.FeedbackPanel);
            app.AftDampCCSpinnerLabel.Text = 'Aft Damp CC';
            app.AftDampCCSpinner = uispinner(app.FeedbackPanel);
            app.AftDampCCSpinner.Limits = [0 200];
            app.AftDampCCSpinner.ValueChangedFcn = createCallbackFcn(app, @AftDampCCSpinnerValueChanged, true);
            app.BowStiffnessSpinnerLabel = uilabel(app.FeedbackPanel);
            app.BowStiffnessSpinnerLabel.Text = 'Bow Stiffness';
            app.BowStiffnessSpinner = uispinner(app.FeedbackPanel);
            app.BowStiffnessSpinner.ValueChangedFcn = createCallbackFcn(app, @BowStiffnessSpinnerValueChanged, true);
            app.BowDampingSpinnerLabel = uilabel(app.FeedbackPanel);
            app.BowDampingSpinnerLabel.Text = 'Bow Damping';
            app.BowDampingSpinner = uispinner(app.FeedbackPanel);
            app.BowDampingSpinner.Step = 5;
            app.BowDampingSpinner.Limits = [0 200];
            app.BowDampingSpinner.ValueChangedFcn = createCallbackFcn(app, @BowDampingSpinnerValueChanged, true);
            app.BowStiffCCSpinnerLabel = uilabel(app.FeedbackPanel);
            app.BowStiffCCSpinnerLabel.Text = 'Bow Stiff CC';
            app.BowStiffCCSpinner = uispinner(app.FeedbackPanel);
            app.BowStiffCCSpinner.ValueChangedFcn = createCallbackFcn(app, @BowStiffCCSpinnerValueChanged, true);
            app.BowDampCCSpinnerLabel = uilabel(app.FeedbackPanel);
            app.BowDampCCSpinnerLabel.Text = 'Bow Damp CC';
            app.BowDampCCSpinner = uispinner(app.FeedbackPanel);
            app.BowDampCCSpinner.Limits = [0 200];
            app.BowDampCCSpinner.ValueChangedFcn = createCallbackFcn(app, @BowDampCCSpinnerValueChanged, true);
            app.flapframeStiffNmDampNmsLabel = uilabel(app.FeedbackPanel);
            app.flapframeStiffNmDampNmsLabel.Text = 'flap frame; Stiff (Nm) ; Damp (Nms)';
            app.EnableFeedbackControlButton = uibutton(app.FeedbackPanel, 'push');
            app.EnableFeedbackControlButton.ButtonPushedFcn = createCallbackFcn(app, @EnableFeedbackControlButtonPushed, true);
            app.EnableFeedbackControlButton.Text = 'Enable Feedback Control';
            app.AftDriveStatusEditFieldLabel = uilabel(app.LeftPanel);
            app.AftDriveStatusEditFieldLabel.Text = 'Aft Drive Status';
            app.AftDriveStatusEditField = uieditfield(app.LeftPanel, 'text');
            app.BowDriveStatusEditFieldLabel = uilabel(app.LeftPanel);
            app.BowDriveStatusEditFieldLabel.Text = 'Bow Drive Status';
            app.BowDriveStatusEditField = uieditfield(app.LeftPanel, 'text');
            app.TETsEditFieldLabel = uilabel(app.LeftPanel);
            app.TETsEditFieldLabel.Text = 'TET (s)';
            app.TETsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TETsEditField.ValueDisplayFormat = '%e';
            app.EnclosurePressurePSILabel = uilabel(app.LeftPanel);
            app.EnclosurePressurePSILabel.Text = 'Enclosure Pressure (PSI)';
            app.EnclosurePressurePSIEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AftBusVEditFieldLabel = uilabel(app.LeftPanel);
            app.AftBusVEditFieldLabel.Text = 'Aft Bus V';
            app.AftBusVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BowBusVEditFieldLabel = uilabel(app.LeftPanel);
            app.BowBusVEditFieldLabel.Text = 'Bow Bus V';
            app.BowBusVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HWRLShareSwitchLabel = uilabel(app.LeftPanel);
            app.HWRLShareSwitchLabel.Text = 'HWRL Share';
            app.HWRLShareSwitch = uiswitch(app.LeftPanel, 'slider');
            app.HWRLShareSwitch.Items = {'No', 'Yes'};
            app.HWRLShareSwitch.ValueChangedFcn = createCallbackFcn(app, @HWRLShareSwitchValueChanged, true);
            app.HWRLShareSwitch.Value = 'Yes';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Aft Current')
            ylabel(app.UIAxes, 'I(A)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Bow Current')
            ylabel(app.UIAxes2, 'I(A)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Aft Position')
            ylabel(app.UIAxes3, '\theta(deg)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Bow Position')
            ylabel(app.UIAxes4, '\theta(deg)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.RightPanel);
            title(app.UIAxes5, 'Aft Power')
            ylabel(app.UIAxes5, 'P(W)')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.RightPanel);
            title(app.UIAxes6, 'Bow Power')
            ylabel(app.UIAxes6, 'P(W)')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.RightPanel);
            title(app.UIAxes7, 'Aft Damping')
            xlabel(app.UIAxes7, 'time(s)')
            ylabel(app.UIAxes7, 'Nms/rad')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.RightPanel);
            title(app.UIAxes8, 'Bow Damping')
            xlabel(app.UIAxes8, 'time(s)')
            ylabel(app.UIAxes8, 'Nms/rad')
            zlabel(app.UIAxes8, 'Z')
            app.StatusBar = slrealtime.ui.control.StatusBar(app.RightPanel);
            app.Panel = uipanel(app.RightPanel);
            app.AftFlapRotationdegGaugeLabel = uilabel(app.Panel);
            app.AftFlapRotationdegGaugeLabel.Text = 'Aft Flap Rotation (deg)';
            app.AftFlapRotationdegGauge = uigauge(app.Panel, 'semicircular');
            app.AftFlapRotationdegGauge.Limits = [-90 90];
            app.Panel_2 = uipanel(app.RightPanel);
            app.BowFlapRotationdegGaugeLabel = uilabel(app.Panel_2);
            app.BowFlapRotationdegGaugeLabel.Text = 'Bow Flap Rotation (deg)';
            app.BowFlapRotationdegGauge = uigauge(app.Panel_2, 'semicircular');
            app.BowFlapRotationdegGauge.Limits = [-90 90];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FOSWEC2app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end